classdef OSDM_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        OutputTextArea          matlab.ui.control.TextArea
        OutputTextAreaLabel     matlab.ui.control.Label
        InputTextArea           matlab.ui.control.TextArea
        InputTextAreaLabel      matlab.ui.control.Label
        Errors1Label            matlab.ui.control.Label
        GeneratemappingsButton  matlab.ui.control.Button
        NoiseCheckBox           matlab.ui.control.CheckBox
        SNRSlider               matlab.ui.control.Slider
        SNRSliderLabel          matlab.ui.control.Label
        FixedRNGCheckBox        matlab.ui.control.CheckBox
        TRANSMITButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        params;
        M;
        targetDs;
        nSymbOSDMTx;
        targetTs;
        Te;
        fse;
        nRefreshPerSymbol;
        realTs;
        realDs;
        channelFrequency;
        ga;
        delay;
        totalDuration;
        symbOSDMDuration;
        timeAxis;
        freqAxis;
        nullFrequencyIdx;
        maxConformFrequency;
        cluster1Size;
        cluster2Size;
        cluster3Size;
        nQAMSymbPerOSDMSymb;
        nSymbTx;
        threshold;
        lengthUpsampled;
        attenuationFactor;
        carriers;
        stats;
        Modulator;
        Decoder;
        carrierReference;
        modulatedCoeffs;
        invModulatedCoeffs;
        mapping;
        invMapping;
        usedCoeffs;
        invUsedCoeffs;
        axhf;
        axht;
        isSeedRandom;
        isNoiseEnabled;
        inputText;
        outputText;
        SNRdB;
        nErrors;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_path();
            
            app.isSeedRandom = true;
            app.isNoiseEnabled = false;
            app.axhf = app.UIAxes;
            app.axht = app.UIAxes2;

            app.params  = genParamsOSDM(2.4825e9, ...
                2.495e9, ...
                10e6, ...
                1e9, ...
                8e9, ...
                250e6, ...
                6, ...
                14, ...
                8, ...
                false, ...
                8e9, ...
                8e9, ...
                400);

            app.M = 6;
            app.targetDs = 2.5e6;
            app.nSymbOSDMTx = 1;
            app.targetTs= 1/app.targetDs; 
            app.Te= 1/app.params.fech;
            app.fse= ceil(app.targetTs/app.Te);
            app.nRefreshPerSymbol = app.fse/64;
            if mod(app.nRefreshPerSymbol, 2) == 1 % Compensation des cas impairs
                app.nRefreshPerSymbol = app.nRefreshPerSymbol + 1;
                app.fse               = app.fse+64;
            end
            app.realTs = app.fse* app.Te;
            app.realDs = ceil(1/app.realTs);
            app.channelFrequency = 500e6;
            app.ga = 1/app.fse * ones(1, app.fse);
            app.delay = 2 * mean(grpdelay(app.ga, 1)) + 1;
            app.totalDuration  = ceil((app.nSymbOSDMTx/app.realDs)*app.params.fech); % signal duration
            app.symbOSDMDuration = app.totalDuration/(app.params.nCoeff*app.params.osr);
            app.timeAxis = (1:app.totalDuration)/app.params.fech;
            app.freqAxis = app.params.freq_axis;
            app.nullFrequencyIdx = ceil(length(app.params.freq_axis)/2);
            app.maxConformFrequency = find(app.params.freq_axis > app.params.fWalsh/2, 1);
            if isempty(app.maxConformFrequency)
                app.maxConformFrequency = app.params.Nfft;
            end
            app.cluster1Size = 4;
            app.cluster2Size = 32;
            app.cluster3Size  = 64-app.cluster2Size-app.cluster1Size;
            app.nQAMSymbPerOSDMSymb = 3;
            app.nSymbTx= app.nQAMSymbPerOSDMSymb*app.nSymbOSDMTx;
            app.threshold= 5e-3;
            app.lengthUpsampled= app.symbOSDMDuration;
            app.attenuationFactor = 4;
            [app.carriers, app.stats] = generateWalshCarrierFixedDurationClusterize(app.params, 100, "tmp.mat", app.totalDuration, ...
                [app.cluster1Size app.cluster2Size app.cluster3Size]);
            app.carrierReference = mean(real(app.carriers{1}.walsh.Xw_b(app.carriers{1}.Clusters{2}, :)), 2);
            load("Combinations_with_walsh_approach_n53.mat", "modulatedCoeffsWalsh");
            app.modulatedCoeffs = modulatedCoeffsWalsh;
            app.invModulatedCoeffs = dictionary(app.modulatedCoeffs.values, app.modulatedCoeffs.keys);
            app.mapping = initMappingProbabilistic(app.modulatedCoeffs);
            app.invMapping = dictionary(app.mapping.values, app.mapping.keys);
            app.usedCoeffs = dictionary(app.mapping.values, modulatedCoeffsWalsh(app.mapping.values));
            app.invUsedCoeffs = dictionary(app.usedCoeffs.values, app.usedCoeffs.keys);
            app.Modulator = walshModulator(app.carriers{1}.walsh.Xw_b, app.modulatedCoeffs, app.attenuationFactor, app.nQAMSymbPerOSDMSymb);
            app.Decoder = walshDecoder(app.carriers{1}.walsh.Xw_b, app.invUsedCoeffs, app.carrierReference);
            app.inputText = "You are hired";
            app.outputText = "You are fired";
            app.SNRdB = 5;
            app.nErrors = 0;

            %% SETUP PLOTS
            title(app.axhf, "Power spectrum", "Interpreter", "latex");
            title(app.axht, "Semantic signal", "Interpreter", "latex");

            hold(app.axhf,"on"); 
            grid(app.axhf, "on");
            xlabel(app.axhf, "Frequency, Hz", "Interpreter","latex");
            ylabel(app.axhf, "Magnitude, dB", "Interpreter","latex");
            plot(app.axhf, app.params.freq_axis, app.params.BW_visible, "LineStyle", "--", "Color", "#EDB120", "LineWidth", 2);
            xlim(app.axhf, [0 4e9]);
            ylim(app.axhf, [-100 0]);

            grid(app.axht, "on");
            xlabel(app.axht, "Time, s", "Interpreter", "latex");
            ylabel(app.axht, "Amplitude", "Interpreter", "latex");
            xlim(app.axht, [0.4e-6 0.8e-6]);

            %% SETUP INPUT FIELDS

            set(app.InputTextArea, "WordWrap", "on");
            set(app.OutputTextArea, "WordWrap", "on");
        end

        % Button pushed function: GeneratemappingsButton
        function GeneratemappingsButtonPushed(app, event)
            if ~app.isSeedRandom
                rng(12);
            end
            load("Combinations_with_walsh_approach_n53.mat", "modulatedCoeffsWalsh");
            app.modulatedCoeffs = modulatedCoeffsWalsh;
            app.invModulatedCoeffs = dictionary(app.modulatedCoeffs.values, app.modulatedCoeffs.keys);
            app.mapping = initMappingProbabilistic(app.modulatedCoeffs);
            app.invMapping = dictionary(app.mapping.values, app.mapping.keys);
            app.usedCoeffs = dictionary(app.mapping.values, modulatedCoeffsWalsh(app.mapping.values));
            app.invUsedCoeffs = dictionary(app.usedCoeffs.values, app.usedCoeffs.keys);
            app.Modulator = walshModulator(app.carriers{1}.walsh.Xw_b, app.modulatedCoeffs, app.attenuationFactor, app.nQAMSymbPerOSDMSymb);
            app.Decoder = walshDecoder(app.carriers{1}.walsh.Xw_b, app.invUsedCoeffs, app.carrierReference);
        end

        % Button pushed function: TRANSMITButton
        function TRANSMITButtonPushed(app, event)
            SNR = 10^(app.SNRdB/10);
            idx = translateSentence(app.inputText, app.mapping);
            nWords = length(idx);
            sig = zeros(nWords * app.fse, 1);
            words = 0;
            for i=1:length(idx)
                words = words + 1;
                idxStart = (i-1)*app.fse+1;
                idxEnd = i*app.fse;
                sig(idxStart:idxEnd) = app.Modulator.generate(idx(i), app.params);
            end
            fprintf("%d words\n", words);


            if app.isNoiseEnabled
                % Canal - pour l'instant sans bruit
                Psig   = 1/length(sig) * sum(abs(sig).^2);
                Pbruit = Psig/SNR;
                
                % Noise
                b = sqrt(Pbruit)*randn(size(sig));
                y = sig + b;
            else
                y  = sig;
            end
        
            % Decodage
            
            rxSigCoeffs = dwt(y, app.params.W, app.params.order, true);
            newSentence = strings(1, nWords);
            for iWords=1:nWords
                idxStart = (iWords-1)*app.symbOSDMDuration+1;
                idxEnd = iWords*app.symbOSDMDuration;
                estimatedIdx = app.Decoder.decode(rxSigCoeffs(:, idxStart:idxEnd), app.carriers{1}.Clusters{1,2});
                if estimatedIdx ~= -1
                    newSentence(iWords) = translateIdx(estimatedIdx, app.invMapping);
                else
                    newSentence(iWords) = "ERROR";
                end
            end
            
            sigFull = analyze(struct("temporel", sig), app.params.Nfft);
            sentence = translateIdx(idx, app.invMapping);

            app.OutputTextArea.Value = strjoin(newSentence," ");
            app.nErrors = sum(~strcmp(strsplit(app.OutputTextArea.Value{1}, " "), strsplit(lower(erasePunctuation(app.inputText)), " ")));
            app.Errors1Label.Text = sprintf("Errors: %d", app.nErrors);

            hold(app.axhf, "off");
            plot(app.axhf, app.params.freq_axis, sigFull.dB, "LineWidth", 3);
            hold(app.axhf,"on"); 
            grid(app.axhf, "on");
            xlabel(app.axhf, "Frequency, Hz", "Interpreter","latex");
            ylabel(app.axhf, "Magnitude, dB", "Interpreter","latex");
            plot(app.axhf, app.params.freq_axis, app.params.BW_visible, "LineStyle", "--", "Color", "#EDB120", "LineWidth", 2);
            xlim(app.axhf, [0 4e9]);
            ylim(app.axhf, [-100 0]);

            plot(app.axht, (1:length(sigFull.temporel))/app.params.fech, sigFull.temporel);
            grid(app.axht, "on");
            xlabel(app.axht, "Time, s", "Interpreter", "latex");
            ylabel(app.axht, "Amplitude", "Interpreter", "latex");
            xlim(app.axht, [0.4e-6 0.8e-6]);
        end

        % Value changed function: SNRSlider
        function SNRSliderValueChanged(app, event)
            app.SNRdB = app.SNRSlider.Value;

        end

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxValueChanged(app, event)
            app.isNoiseEnabled = app.NoiseCheckBox.Value;
        end

        % Value changed function: FixedRNGCheckBox
        function FixedRNGCheckBoxValueChanged(app, event)
            app.isSeedRandom = logical(app.FixedRNGCheckBox.Value);

        end

        % Value changed function: InputTextArea
        function InputTextAreaValueChanged(app, event)
            app.inputText = app.InputTextArea.Value{1};
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {657, 657};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {490, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TRANSMITButton = uibutton(app.LeftPanel, 'push');
            app.TRANSMITButton.ButtonPushedFcn = createCallbackFcn(app, @TRANSMITButtonPushed, true);
            app.TRANSMITButton.Text = 'TRANSMIT';
            app.FixedRNGCheckBox = uicheckbox(app.LeftPanel);
            app.FixedRNGCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedRNGCheckBoxValueChanged, true);
            app.FixedRNGCheckBox.Text = 'Fixed RNG';
            app.SNRSliderLabel = uilabel(app.LeftPanel);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.LeftPanel);
            app.SNRSlider.Limits = [-30 15];
            app.SNRSlider.ValueChangedFcn = createCallbackFcn(app, @SNRSliderValueChanged, true);
            app.SNRSlider.MinorTicks = [];
            app.NoiseCheckBox = uicheckbox(app.LeftPanel);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.GeneratemappingsButton = uibutton(app.LeftPanel, 'push');
            app.GeneratemappingsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemappingsButtonPushed, true);
            app.GeneratemappingsButton.Text = 'Generate mappings';
            app.Errors1Label = uilabel(app.LeftPanel);
            app.Errors1Label.Text = 'Errors: 1';
            app.InputTextAreaLabel = uilabel(app.LeftPanel);
            app.InputTextAreaLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.LeftPanel);
            app.InputTextArea.ValueChangedFcn = createCallbackFcn(app, @InputTextAreaValueChanged, true);
            app.InputTextArea.Value = {'You are hired'};
            app.OutputTextAreaLabel = uilabel(app.LeftPanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.LeftPanel);
            app.OutputTextArea.Editable = 'off';
            app.OutputTextArea.Value = {'You are fired'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSDM_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end