classdef sweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GainEditField               matlab.ui.control.NumericEditField
        GainEditFieldLabel          matlab.ui.control.Label
        FrequencecentraleEditField  matlab.ui.control.NumericEditField
        FrequencecentraleEditFieldLabel  matlab.ui.control.Label
        ChangerfreqLamp             matlab.ui.control.Lamp
        ChangerfreqLampLabel        matlab.ui.control.Label
        EmissionLamp                matlab.ui.control.Lamp
        EmissionLampLabel           matlab.ui.control.Label
        StartButton                 matlab.ui.control.StateButton
    end


    properties (Access = private)
        tx
        pauseTime
        M
        samplesPerFrame
        symbolsPerFrame
        fse
        txSampleRate
        txSampleDuration
        txFrameDuration
        numFrames
        Ds
        nBits
        erroredFrames
        carriers
        gains
    end

    methods (Access = public)

        function emit(app)
            for uselessIteratorVariable = 1:app.numFrames
                tx_signal_norm = generateNewUSRPFrameQPSK(app.symbolsPerFrame, app.fse, length(app.tx.ChannelMapping));
                underrun = step(app.tx,tx_signal_norm);
                if(underrun)
                    fprintf('Underrun\n');
                    % erroredFrames = errored_frames + 1;
                end
            end
        end

        function sweep(app)
            LIT = [1.0 1.0 1.0];
            OFF = [0.0 0.0 0.0];
            for iGains = 1:length(app.gains)
                app.tx.Gain = app.gains(iGains);
                app.GainEditField.Value = app.tx.Gain;
                app.EmissionLamp.Color = LIT;
                drawnow
                app.emit();
                app.EmissionLamp.Color = OFF;
                drawnow
                pause(2);
            end
            
        end

        function startSweep(app)

            LIT = [1.0 1.0 1.0];
            OFF = [0.0 0.0 0.0];

            for iCarriers = 1:length(app.carriers)
                app.tx.CenterFrequency = app.carriers(iCarriers);
                app.FrequencecentraleEditField.Value = app.tx.CenterFrequency;
                app.ChangerfreqLamp.Color = LIT;
                pause(6);
                app.ChangerfreqLamp.Color = OFF;
                app.sweep();
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.carriers = [600e6];% 833e6 1.5e9];
            app.gains = 3:3:45;
            
            app.pauseTime = 2;
            
            app.symbolsPerFrame = 60;
            app.samplesPerFrame = 900;
            app.fse = app.samplesPerFrame/app.symbolsPerFrame;
            app.M = 2;
               
            app.tx                       = comm.SDRuTransmitter('Platform','B210','SerialNum','32752A2');
            app.tx.ChannelMapping        = [1 2];
            % app.tx.CenterFrequency = app.carriers(1);
            % app.tx.Gain = 35;
            app.tx.LocalOscillatorOffset = 0;
            app.tx.PPSSource             = 'Internal';
            app.tx.ClockSource           = 'Internal';
            app.tx.MasterClockRate       = 30e6;
            app.tx.InterpolationFactor   = 4;
            app.tx.TransportDataType     = 'int16';
            app.tx.EnableBurstMode       = true;
            if(app.tx.EnableBurstMode)
                app.tx.NumFramesInBurst = 100;
            end

            txMinDuration = 5;
            app.txSampleRate = app.tx.MasterClockRate/app.tx.InterpolationFactor;
            app.txSampleDuration = 1/app.txSampleRate;
            app.txFrameDuration = app.txSampleDuration*app.samplesPerFrame;
            app.numFrames = ceil(txMinDuration/app.txFrameDuration);
            app.Ds = app.symbolsPerFrame/app.txFrameDuration;
            disp(app.Ds);
            app.nBits = app.symbolsPerFrame*app.M;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.startSweep();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.EmissionLampLabel = uilabel(app.UIFigure);
            app.EmissionLampLabel.Text = 'Emission';
            app.EmissionLamp = uilamp(app.UIFigure);
            app.ChangerfreqLampLabel = uilabel(app.UIFigure);
            app.ChangerfreqLampLabel.Text = 'Changer freq';
            app.ChangerfreqLamp = uilamp(app.UIFigure);
            app.FrequencecentraleEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencecentraleEditFieldLabel.Text = 'Frequence centrale';
            app.FrequencecentraleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencecentraleEditField.Editable = 'off';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end